classdef RiverMoniter_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        text1                matlab.ui.control.Label
        edit1                matlab.ui.control.EditField
        text2                matlab.ui.control.Label
        edit2                matlab.ui.control.EditField
        text3                matlab.ui.control.Label
        checkbox1            matlab.ui.control.CheckBox
        pushbutton1          matlab.ui.control.Button
        checkbox3            matlab.ui.control.CheckBox
        checkbox4            matlab.ui.control.CheckBox
        text6                matlab.ui.control.Label
        ylim_min             matlab.ui.control.EditField
        text8                matlab.ui.control.Label
        ylim_max             matlab.ui.control.EditField
        DrawModeButtonGroup  matlab.ui.container.ButtonGroup
        radioButton1         matlab.ui.control.RadioButton
        radioButton2         matlab.ui.control.RadioButton
        radioButton3         matlab.ui.control.RadioButton
        minEditFieldLabel    matlab.ui.control.Label
        xMin                 matlab.ui.control.NumericEditField
        maxEditFieldLabel    matlab.ui.control.Label
        xMax                 matlab.ui.control.NumericEditField
        text6_2              matlab.ui.control.Label
    end

    
    properties (Access = private)
        expData % 导出数据
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RiverMoniter_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to RiverMoniter (see VARARGIN)
%             
%             app.DrawMode=0;
%             app.filter=0;
%             app.yn_video=0;
%             % Choose default command line output for RiverMoniter
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
            app.expData.nCS=0;
            app.expData.nplg_lim=0;
            app.expData.DrawMode=0;
            app.expData.filter=0;
            app.expData.yn_video=0;
            app.expData.ylim_minV=-inf;
            app.expData.ylim_maxV=inf;
            app.expData.xlim_minV=app.xMin.Value;
            app.expData.xlim_maxV=app.xMax.Value;
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            app.expData.filter=app.checkbox1.Value;
        end

        % Value changed function: checkbox3
        function checkbox3_Callback(app, event)
            % hObject    handle to checkbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox3
            app.expData.yn_video=app.checkbox3.Value;
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            %app.expData=str2double(get(hObject,'String'));
            app.expData.nCS=str2double(app.edit1.Value);
            %guidata(hObject, handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            app.expData.nplg_lim=str2double(app.edit2.Value);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                        
            str_tmp=app.ylim_max.Value;
            if isempty(str_tmp)
                app.expData.ylim_maxV=Inf;
            else
                app.expData.ylim_maxV=str2double(str_tmp);
            end
            
            str_tmp=app.ylim_min.Value;
            if isempty(str_tmp)
                app.expData.ylim_minV=-Inf;
            else
                app.expData.ylim_minV=str2double(str_tmp);
            end        
            
            app.expData.xlim_minV=app.xMin.Value;
            app.expData.xlim_maxV=app.xMax.Value;
            
            [dist, zb_av0, zb_av, zw_max]=ShowFig(app.expData);
            save('show_fig_output.mat', 'dist', 'zb_av0', 'zb_av', 'zw_max');
        end

        % Value changed function: ylim_max
        function ylim_max_Callback(app, event)
            % hObject    handle to ylim_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ylim_max as text
            %        str2double(get(hObject,'String')) returns contents of ylim_max as a double

        end

        % Value changed function: ylim_min
        function ylim_min_Callback(app, event)
            % hObject    handle to ylim_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ylim_min as text
            %        str2double(get(hObject,'String')) returns contents of ylim_min as a double
        end

        % Selection changed function: DrawModeButtonGroup
        function DrawModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DrawModeButtonGroup.SelectedObject;
            if selectedButton==app.radioButton1
                app.expData.DrawMode=0;
                app.ylim_min.Enable='off';
                app.ylim_max.Enable='off';
            elseif selectedButton==app.radioButton2
                app.expData.DrawMode=1;
                app.ylim_min.Enable='on';
                app.ylim_max.Enable='on';
            elseif selectedButton==app.radioButton3
                app.expData.DrawMode=2;
                app.ylim_min.Enable='off';
                app.ylim_max.Enable='off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RiverMoniter';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'number of cross-sections';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'plunge point is restricted to the downstream of the';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'cross-section';
            app.checkbox1 = uicheckbox(app.figure1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'apply filter';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Start';
            app.checkbox3 = uicheckbox(app.figure1);
            app.checkbox3.ValueChangedFcn = createCallbackFcn(app, @checkbox3_Callback, true);
            app.checkbox3.Text = 'make video';
            app.checkbox4 = uicheckbox(app.figure1);
            app.checkbox4.Text = 'make gif';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'YLim of zw plot: min ';
            app.ylim_min = uieditfield(app.figure1, 'text');
            app.ylim_min.ValueChangedFcn = createCallbackFcn(app, @ylim_min_Callback, true);
            app.ylim_min.Enable = 'off';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'max';
            app.ylim_max = uieditfield(app.figure1, 'text');
            app.ylim_max.ValueChangedFcn = createCallbackFcn(app, @ylim_max_Callback, true);
            app.ylim_max.Enable = 'off';
            app.DrawModeButtonGroup = uibuttongroup(app.figure1);
            app.DrawModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrawModeButtonGroupSelectionChanged, true);
            app.DrawModeButtonGroup.Title = 'Draw Mode:';
            app.radioButton1 = uiradiobutton(app.DrawModeButtonGroup);
            app.radioButton1.Text = 'draw turbidity current and open channel flow';
            app.radioButton1.Value = true;
            app.radioButton2 = uiradiobutton(app.DrawModeButtonGroup);
            app.radioButton2.Text = 'only draw open channel flow';
            app.radioButton3 = uiradiobutton(app.DrawModeButtonGroup);
            app.radioButton3.Text = 'only draw the levels of surface and interface';
            app.minEditFieldLabel = uilabel(app.figure1);
            app.minEditFieldLabel.Text = 'min';
            app.xMin = uieditfield(app.figure1, 'numeric');
            app.xMin.Value = -Inf;
            app.maxEditFieldLabel = uilabel(app.figure1);
            app.maxEditFieldLabel.Text = 'max';
            app.xMax = uieditfield(app.figure1, 'numeric');
            app.xMax.Value = Inf;
            app.text6_2 = uilabel(app.figure1);
            app.text6_2.Text = 'XLim of zw plot:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RiverMoniter_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @RiverMoniter_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end